classdef photocellgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        StartButton              matlab.ui.control.Button
        DutyCycleEditFieldLabel  matlab.ui.control.Label
        DutyCycleEditField       matlab.ui.control.NumericEditField
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        EndButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        %position = [ll]; % Description
        %arduino;
        data;
        status;
        command;    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Visible = false;
            app.EndButton.Visible = true;
            arduino = serial("/dev/cu.usbmodem142301",'BaudRate',9600);
            pause(1)
            fopen(arduino);
            writedata=uint16(500); %0x01F4
            VR = [0];
            PWM = 0:1:100;
            for i=1:101
                fwrite(arduino,writedata,'uint16');
                pause(.5)
                readData = fscanf(arduino,'%f');
                VR = [VR readData]; 
                Vph = VR(2:end);
                PhotocellResistance = (5*10000./Vph)-10000;
                PhotocellCurrent = 5./(10000+PhotocellResistance);
                PhotocellVoltage = 5 - Vph;
                app.DutyCycleEditField.Value = i-1;
                plot(app.UIAxes,PWM(1:i), PhotocellCurrent(1:i),'b','LineWidth',2)
                plot(app.UIAxes2,PWM(1:i), PhotocellVoltage(1:i), 'b','LineWidth',2)
                plot(app.UIAxes3,PWM(1:i), PhotocellResistance(1:i), 'b','LineWidth',2)
            end

            fclose(arduino);
            
           
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            app.DutyCycleEditField.Value = 0;
            app.EndButton.Visible = false;
            app.StartButton.Visible = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DutyCycleEditFieldLabel = uilabel(app.UIFigure);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle (%)';
            app.DutyCycleEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Photocell Current vs Duty Cycle')
            xlabel(app.UIAxes, 'Duty Cycle (%)')
            ylabel(app.UIAxes, 'Current (mA)')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Photocell Voltage vs Duty Cycle')
            xlabel(app.UIAxes2, 'Duty Cycle (%)')
            ylabel(app.UIAxes2, 'Voltage (V)')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Photocell Resistance vs Duty Cycle')
            xlabel(app.UIAxes3, 'Duty Cycle (%)')
            ylabel(app.UIAxes3, 'Resistanace (Ω)')
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = photocellgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end